classdef ABS_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        RunFISButton                 matlab.ui.control.Button
        QuartermasskgEditFieldLabel  matlab.ui.control.Label
        QuartermasskgEditField       matlab.ui.control.NumericEditField
        WheelradiusmEditFieldLabel   matlab.ui.control.Label
        WheelradiusmEditField        matlab.ui.control.NumericEditField
        InitialvelocitymsEditFieldLabel  matlab.ui.control.Label
        InitialvelocitymsEditField   matlab.ui.control.NumericEditField
        UIAxes3                      matlab.ui.control.UIAxes
        GeneticAlgorithmButton       matlab.ui.control.Button
        PSOButton                    matlab.ui.control.Button
        PEditFieldLabel              matlab.ui.control.Label
        PEditField                   matlab.ui.control.NumericEditField
        IEditFieldLabel              matlab.ui.control.Label
        IEditField                   matlab.ui.control.NumericEditField
        DEditFieldLabel              matlab.ui.control.Label
        DEditField                   matlab.ui.control.NumericEditField
        RoadtypeListBoxLabel         matlab.ui.control.Label
        RoadtypeListBox              matlab.ui.control.ListBox
        ParticleNoEditFieldLabel     matlab.ui.control.Label
        ParticleNoEditField          matlab.ui.control.NumericEditField
        IterationNoEditFieldLabel    matlab.ui.control.Label
        IterationNoEditField         matlab.ui.control.NumericEditField
        IndividualNoEditFieldLabel   matlab.ui.control.Label
        IndividualNoEditField        matlab.ui.control.NumericEditField
        GenerationNoEditFieldLabel   matlab.ui.control.Label
        GenerationNoEditField        matlab.ui.control.NumericEditField
        RunDefaultButton             matlab.ui.control.Button
    end


    properties (Access = public)
        m = 342;
        R = 0.33;
        v_x = 27.78;
        sel = 1;
        Kpid = [6.171910245247532e+02 0 1.252365995617769e+02];
        PID = [
                2.638939022838257e+02 0 50.597476085793020
                4.165192145690851e+02 0 1.127282083249942e+02
                4.33005e+02 0 1.05768e+02
                3.885045690906933e+02 1000 97.882933276027880
                3.612335217514744e+02 6.594113059585302e+02 1.764244611373335e+02
                1.30504e+02 0 1.25237e+02
        ];
    end


    methods (Access = private)

        % Button pushed function: RunFISButton
        function RunFISButtonPushed(app, event)
            assignin('base','m',app.m);
            assignin('base','R',app.R);
            assignin('base','v_x',app.v_x);
            assignin('base','PID',app.PID);
            assignin('base','Kpid_s',app.Kpid);
            assignin('base','Kpid_s_off',app.Kpid);
            switch app.RoadtypeListBox.Value
                case 'Dry Asphalt'
                    assignin('base','sel',1);
                case 'Wet Asphalt'
                    assignin('base','sel',2);
                case 'Dry Cement'
                    assignin('base','sel',3);
                case 'Wet Cobblestone'
                    assignin('base','sel',4);
                case 'Snowy'
                    assignin('base','sel',5);
                case 'Icy'
                    assignin('base','sel',6);
                case 'Default'
                    assignin('base','sel',1);
            end
            sim('ABS_fuzzy');
            assignin('base','wheel_v',wheel_v);
            assignin('base','stopping_d',stopping_d);
            sim('ABS_off');
            assignin('base','wheel_v_off',wheel_v_off);
            assignin('base','stopping_d_off',stopping_d_off);
            sim('ABS_car');
            sim('ABS_wheel');
            plot(app.UIAxes, velocities.time, velocities.signals.values);
            plot(app.UIAxes2, slips.time, slips.signals.values);
            plot(app.UIAxes3, stopping_dist.time, stopping_dist.signals.values);
            app.PEditField.Value = Kpid_s(end,1);
            app.IEditField.Value = Kpid_s(end,2);
            app.DEditField.Value = Kpid_s(end,3);
        end

        % Value changed function: QuartermasskgEditField
        function QuartermasskgEditFieldValueChanged(app, event)
            value = app.QuartermasskgEditField.Value;
            app.m = value;
        end

        % Value changed function: WheelradiusmEditField
        function WheelradiusmEditFieldValueChanged(app, event)
            value = app.WheelradiusmEditField.Value;
            app.R = value;
        end

        % Value changed function: InitialvelocitymsEditField
        function InitialvelocitymsEditFieldValueChanged(app, event)
            value = app.InitialvelocitymsEditField.Value;
            app.v_x = value;
        end

        % Button pushed function: GeneticAlgorithmButton
        function GeneticAlgorithmButtonPushed(app, event)
            assignin('base','m',app.m);
            assignin('base','R',app.R);
            assignin('base','v_x',app.v_x);
            assignin('base', 'NIND', app.IndividualNoEditField.Value);
            assignin('base', 'MAXGEN', app.GenerationNoEditField.Value);
            switch app.RoadtypeListBox.Value
                case 'Dry Asphalt'
                    app.sel = 1;
                    assignin('base','sel',1);
                    assignin('base','type',2);
                case 'Wet Asphalt'
                    app.sel = 2;
                    assignin('base','sel',2);
                    assignin('base','type',2);
                case 'Dry Cement'
                    app.sel = 3;
                    assignin('base','sel',3);
                    assignin('base','type',2);
                case 'Wet Cobblestone'
                    app.sel = 4;
                    assignin('base','sel',4);
                    assignin('base','type',2);
                case 'Snowy'
                    app.sel = 5;
                    assignin('base','sel',5);
                    assignin('base','type',2);
                case 'Icy'
                    app.sel = 6;
                    assignin('base','sel',6);
                    assignin('base','type',2);
                case 'Default'
                    assignin('base','sel',7);
                    assignin('base','type',1);
            end
            evalin('base','ABS_ga');
            sim('ABS_opt');
            plot(app.UIAxes, velocities_opt.time, velocities_opt.signals.values);
            plot(app.UIAxes3, stopping_dist_opt.time, stopping_dist_opt.signals.values);
            plot(app.UIAxes2, slip_opt.time, slip_opt.signals.values);
            app.PEditField.Value = evalin('base','Kpid(1)');
            app.IEditField.Value = evalin('base','Kpid(2)');
            app.DEditField.Value = evalin('base','Kpid(3)');
            app.PID(app.sel,1) = evalin('base','Kpid(1)');
            app.PID(app.sel,2) = evalin('base','Kpid(2)');
            app.PID(app.sel,3) = evalin('base','Kpid(3)');
            if strcmp(app.RoadtypeListBox.Value,'Default')
                app.Kpid(1) = evalin('base','Kpid(1)');
                app.Kpid(2) = evalin('base','Kpid(2)');
                app.Kpid(3) = evalin('base','Kpid(3)');
            end
        end

        % Callback function
        function PEditFieldValueChanged(app, event)
            value = app.PEditField.Value;
            app.Kpid(1) = value;
        end

        % Callback function
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            app.Kpid(2) = value;
        end

        % Callback function
        function DEditFieldValueChanged(app, event)
            value = app.DEditField.Value;
            app.Kpid(3) = value;
        end

        % Button pushed function: PSOButton
        function PSOButtonPushed(app, event)
            assignin('base','m',app.m);
            assignin('base','R',app.R);
            assignin('base','v_x',app.v_x);
            assignin('base', 'NoP', app.ParticleNoEditField.Value);
            assignin('base', 'maxIter', app.IterationNoEditField.Value);
            switch app.RoadtypeListBox.Value
                case 'Dry Asphalt'
                    app.sel = 1;
                    assignin('base','sel',1);
                    assignin('base','type',2);
                case 'Wet Asphalt'
                    app.sel = 2;
                    assignin('base','sel',2);
                    assignin('base','type',2);
                case 'Dry Cement'
                    app.sel = 3;
                    assignin('base','sel',3);
                    assignin('base','type',2);
                case 'Wet Cobblestone'
                    app.sel = 4;
                    assignin('base','sel',4);
                    assignin('base','type',2);
                case 'Snowy'
                    app.sel = 5;
                    assignin('base','sel',5);
                    assignin('base','type',2);
                case 'Icy'
                    app.sel = 6;
                    assignin('base','sel',6);
                    assignin('base','type',2);
                case 'Default'
                    assignin('base','sel',7);
                    assignin('base','type',1);
            end
            evalin('base','ABS_pso');
            sim('ABS_opt');
            plot(app.UIAxes, velocities_opt.time, velocities_opt.signals.values);
            plot(app.UIAxes3, stopping_dist_opt.time, stopping_dist_opt.signals.values);
            plot(app.UIAxes2, slip_opt.time, slip_opt.signals.values);
            app.PEditField.Value = evalin('base','Kpid(1)');
            app.IEditField.Value = evalin('base','Kpid(2)');
            app.DEditField.Value = evalin('base','Kpid(3)');
            app.PID(app.sel,1) = evalin('base','Kpid(1)');
            app.PID(app.sel,2) = evalin('base','Kpid(2)');
            app.PID(app.sel,3) = evalin('base','Kpid(3)');
            if strcmp(app.RoadtypeListBox.Value,'Default')
                app.Kpid(1) = evalin('base','Kpid(1)');
                app.Kpid(2) = evalin('base','Kpid(2)');
                app.Kpid(3) = evalin('base','Kpid(3)');
            end
        end

        % Value changed function: RoadtypeListBox
        function RoadtypeListBoxValueChanged(app, event)
            value = app.RoadtypeListBox.Value;
            switch value
                case 'Dry Asphalt'
                    app.sel = 1;
                    app.PEditField.Value = app.PID(app.sel,1);
                    app.IEditField.Value = app.PID(app.sel,2);
                    app.DEditField.Value = app.PID(app.sel,3);
                case 'Wet Asphalt'
                    app.sel = 2;
                    app.PEditField.Value = app.PID(app.sel,1);
                    app.IEditField.Value = app.PID(app.sel,2);
                    app.DEditField.Value = app.PID(app.sel,3);
                case 'Dry Cement'
                    app.sel = 3;
                    app.PEditField.Value = app.PID(app.sel,1);
                    app.IEditField.Value = app.PID(app.sel,2);
                    app.DEditField.Value = app.PID(app.sel,3);
                case 'Wet Cobblestone'
                    app.sel = 4;
                    app.PEditField.Value = app.PID(app.sel,1);
                    app.IEditField.Value = app.PID(app.sel,2);
                    app.DEditField.Value = app.PID(app.sel,3);
                case 'Snowy'
                    app.sel = 5;
                    app.PEditField.Value = app.PID(app.sel,1);
                    app.IEditField.Value = app.PID(app.sel,2);
                    app.DEditField.Value = app.PID(app.sel,3);
                case 'Icy'
                    app.sel = 6;
                    app.PEditField.Value = app.PID(app.sel,1);
                    app.IEditField.Value = app.PID(app.sel,2);
                    app.DEditField.Value = app.PID(app.sel,3);
                case 'Default'
                    app.PEditField.Value = app.Kpid(1);
                    app.IEditField.Value = app.Kpid(2);
                    app.DEditField.Value = app.Kpid(3);
            end
        end

        % Button pushed function: RunDefaultButton
        function RunDefaultButtonPushed(app, event)
            assignin('base','m',app.m);
            assignin('base','R',app.R);
            assignin('base','v_x',app.v_x);
            assignin('base','PID',app.PID);
            assignin('base','Kpid',app.Kpid);
            assignin('base','Kpid_s_off',app.Kpid);
            switch app.RoadtypeListBox.Value
                case 'Dry Asphalt'
                    assignin('base','sel',1);
                case 'Wet Asphalt'
                    assignin('base','sel',2);
                case 'Dry Cement'
                    assignin('base','sel',3);
                case 'Wet Cobblestone'
                    assignin('base','sel',4);
                case 'Snowy'
                    assignin('base','sel',5);
                case 'Icy'
                    assignin('base','sel',6);
                case 'Default'
                    assignin('base','sel',1);
            end
            sim('ABS_default');
            assignin('base','wheel_v',wheel_v);
            assignin('base','stopping_d',stopping_d);
            sim('ABS_off');
            assignin('base','wheel_v_off',wheel_v_off);
            assignin('base','stopping_d_off',stopping_d_off);
            sim('ABS_car');
            sim('ABS_wheel');
            plot(app.UIAxes, velocities_def.time, velocities_def.signals.values);
            plot(app.UIAxes2, slips_def.time, slips_def.signals.values);
            plot(app.UIAxes3, stopping_dist_def.time, stopping_dist_def.signals.values);
            app.PEditField.Value = app.Kpid(1);
            app.IEditField.Value = app.Kpid(2);
            app.DEditField.Value = app.Kpid(3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wheel&vehicle vel.')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'v [m/s]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Slip')
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, 'λ')
            app.RunFISButton = uibutton(app.UIFigure, 'push');
            app.RunFISButton.ButtonPushedFcn = createCallbackFcn(app, @RunFISButtonPushed, true);
            app.RunFISButton.Text = 'Run (FIS)';
            app.QuartermasskgEditFieldLabel = uilabel(app.UIFigure);
            app.QuartermasskgEditFieldLabel.Text = {'Quarter'; 'mass'; '[kg]'};
            app.QuartermasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuartermasskgEditField.ValueDisplayFormat = '%.4f';
            app.QuartermasskgEditField.ValueChangedFcn = createCallbackFcn(app, @QuartermasskgEditFieldValueChanged, true);
            app.QuartermasskgEditField.Value = 342;
            app.WheelradiusmEditFieldLabel = uilabel(app.UIFigure);
            app.WheelradiusmEditFieldLabel.Text = {'Wheel'; 'radius'; '[m]'};
            app.WheelradiusmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WheelradiusmEditField.ValueDisplayFormat = '%.4f';
            app.WheelradiusmEditField.ValueChangedFcn = createCallbackFcn(app, @WheelradiusmEditFieldValueChanged, true);
            app.WheelradiusmEditField.Value = 0.33;
            app.InitialvelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialvelocitymsEditFieldLabel.Text = {'Initial'; 'velocity'; '[m/s]'};
            app.InitialvelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialvelocitymsEditField.ValueDisplayFormat = '%.4f';
            app.InitialvelocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialvelocitymsEditFieldValueChanged, true);
            app.InitialvelocitymsEditField.Value = 27.78;
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Stopping dist.')
            xlabel(app.UIAxes3, 't [s]')
            ylabel(app.UIAxes3, 'd [m]')
            app.GeneticAlgorithmButton = uibutton(app.UIFigure, 'push');
            app.GeneticAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @GeneticAlgorithmButtonPushed, true);
            app.GeneticAlgorithmButton.Text = 'Genetic Algorithm';
            app.PSOButton = uibutton(app.UIFigure, 'push');
            app.PSOButton.ButtonPushedFcn = createCallbackFcn(app, @PSOButtonPushed, true);
            app.PSOButton.Text = 'PSO';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.ValueDisplayFormat = '%.4f';
            app.PEditField.Editable = 'off';
            app.PEditField.Value = 617.191;
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.ValueDisplayFormat = '%.4f';
            app.IEditField.Editable = 'off';
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.ValueDisplayFormat = '%.4f';
            app.DEditField.Editable = 'off';
            app.DEditField.Value = 125.2366;
            app.RoadtypeListBoxLabel = uilabel(app.UIFigure);
            app.RoadtypeListBoxLabel.Text = 'Road type';
            app.RoadtypeListBox = uilistbox(app.UIFigure);
            app.RoadtypeListBox.Items = {'Dry Asphalt', 'Wet Asphalt', 'Dry Cement', 'Wet Cobblestone', 'Snowy', 'Icy', 'Default'};
            app.RoadtypeListBox.ValueChangedFcn = createCallbackFcn(app, @RoadtypeListBoxValueChanged, true);
            app.RoadtypeListBox.Value = 'Default';
            app.ParticleNoEditFieldLabel = uilabel(app.UIFigure);
            app.ParticleNoEditFieldLabel.Text = 'Particle No.';
            app.ParticleNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParticleNoEditField.Limits = [1 Inf];
            app.ParticleNoEditField.ValueDisplayFormat = '%.0f';
            app.ParticleNoEditField.Value = 30;
            app.IterationNoEditFieldLabel = uilabel(app.UIFigure);
            app.IterationNoEditFieldLabel.Text = 'Iteration No.';
            app.IterationNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationNoEditField.Limits = [1 Inf];
            app.IterationNoEditField.ValueDisplayFormat = '%.0f';
            app.IterationNoEditField.Value = 20;
            app.IndividualNoEditFieldLabel = uilabel(app.UIFigure);
            app.IndividualNoEditFieldLabel.Text = 'Individual No.';
            app.IndividualNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndividualNoEditField.Limits = [1 Inf];
            app.IndividualNoEditField.ValueDisplayFormat = '%.0f';
            app.IndividualNoEditField.Value = 30;
            app.GenerationNoEditFieldLabel = uilabel(app.UIFigure);
            app.GenerationNoEditFieldLabel.Text = 'Generation No.';
            app.GenerationNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerationNoEditField.Limits = [1 Inf];
            app.GenerationNoEditField.ValueDisplayFormat = '%.0f';
            app.GenerationNoEditField.Value = 80;
            app.RunDefaultButton = uibutton(app.UIFigure, 'push');
            app.RunDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RunDefaultButtonPushed, true);
            app.RunDefaultButton.Text = 'Run (Default)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ABS_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end